classdef Bisection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnterFunctionfxEditFieldLabel   matlab.ui.control.Label
        EnterFunctionfxEditField        matlab.ui.control.EditField
        Input1stApproximationEditFieldLabel  matlab.ui.control.Label
        Input1stApproximationEditField  matlab.ui.control.NumericEditField
        Input2ndApproximationEditFieldLabel  matlab.ui.control.Label
        Input2ndApproximationEditField  matlab.ui.control.NumericEditField
        InputToleranceEditFieldLabel    matlab.ui.control.Label
        InputToleranceEditField         matlab.ui.control.NumericEditField
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        MaximumIterationsEditField      matlab.ui.control.NumericEditField
        ApproximateRootTextAreaLabel    matlab.ui.control.Label
        ApproximateRootTextArea         matlab.ui.control.TextArea
        CalulateButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: CalulateButton
        function CalulateButtonPushed(app, event)
            
            f=str2func(['@(x)',app.EnterFunctionfxEditField.Value]);
            a=app.Input1stApproximationEditField.Value;
            b=app.Input2ndApproximationEditField.Value;
            tol=app.InputToleranceEditField.Value;
            iterlimit=app.MaximumIterationsEditField.Value;
            %Code for Calculating root
            FA=f(a);
            FB=f(b);
            
            if FA*FB>0
                app.UITable.Data=[];
                
                ylimit=round(max(abs([f(app.Input1stApproximationEditField.Value-3:1e-3:app.Input2ndApproximationEditField.Value+3)])));
                ylim(app.UIAxes,[-ylimit-.3,ylimit+.3]);
                ylim(app.UIAxes,'manual');
                yticks(app.UIAxes,[-ylimit:ylimit]);
                
                
                xlimit=max(abs([app.Input1stApproximationEditField.Value,app.Input2ndApproximationEditField.Value]));
                xlim(app.UIAxes,[-xlimit-3.3,xlimit+3.3]);
                xlim(app.UIAxes,'manual');
                xticks(app.UIAxes,[floor(-xlimit-3):ceil(xlimit+3)]);
                fplot(app.UIAxes,f,[-xlimit-3.3,xlimit+3.3])
                
                app.ApproximateRootTextArea.Value=sprintf('Solution doesn''t exist in this interval=>[%d,%d]',a,b);
                return;
            end
            
            iter=0;
            p_old=b;
            while iter<iterlimit
                iter=iter+1;
                p=(a+b)/2;
                FP=f(p);
                %Vectors for creating table
                iter_col(iter)=iter;
                a_col(iter)=a;
                FA_col(iter)=FA;
                b_col(iter)=b;
                FB_col(iter)=FB;
                p_col(iter)=p;
                FP_col(iter)=FP;
                
                if abs((p-p_old))<tol
                    break;
                end
                if FA*FP>0
                    a=p;
                    p_old=p;
                else
                    b=p;
                    p_old=p;
                end
            end
            app.ApproximateRootTextArea.Value=string(p);
            app.UITable.Data=[iter_col;a_col;FA_col;b_col;FB_col;p_col;FP_col]';
            
            ylimit=round(max(abs([f(app.Input1stApproximationEditField.Value-3:1e-3:app.Input2ndApproximationEditField.Value+3)])));
            ylim(app.UIAxes,[-ylimit-.3,ylimit+.3]);
            ylim(app.UIAxes,'manual');
            yticks(app.UIAxes,[-ylimit:ylimit]);
            
            
            xlimit=max(abs([app.Input1stApproximationEditField.Value,app.Input2ndApproximationEditField.Value]));
            xlim(app.UIAxes,[-xlimit-3.3,xlimit+3.3]);
            xlim(app.UIAxes,'manual');
            xticks(app.UIAxes,[floor(-xlimit-3):ceil(xlimit+3)]);
            fplot(app.UIAxes,f,[-xlimit-3.3,xlimit+3.3])
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnterFunctionfxEditFieldLabel = uilabel(app.UIFigure);
            app.EnterFunctionfxEditFieldLabel.Text = 'Enter Function f(x)=';
            app.EnterFunctionfxEditField = uieditfield(app.UIFigure, 'text');
            app.EnterFunctionfxEditField.Value = 'x-cos(x)';
            app.Input1stApproximationEditFieldLabel = uilabel(app.UIFigure);
            app.Input1stApproximationEditFieldLabel.Text = 'Input 1st Approximation=';
            app.Input1stApproximationEditField = uieditfield(app.UIFigure, 'numeric');
            app.Input1stApproximationEditField.Value = 1;
            app.Input2ndApproximationEditFieldLabel = uilabel(app.UIFigure);
            app.Input2ndApproximationEditFieldLabel.Text = 'Input 2nd Approximation=';
            app.Input2ndApproximationEditField = uieditfield(app.UIFigure, 'numeric');
            app.Input2ndApproximationEditField.Value = 2;
            app.InputToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.InputToleranceEditFieldLabel.Text = 'Input Tolerance=';
            app.InputToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputToleranceEditField.Value = 0.001;
            app.MaximumIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumIterationsEditFieldLabel.Text = 'Maximum Iterations=';
            app.MaximumIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumIterationsEditField.Value = 100;
            app.ApproximateRootTextAreaLabel = uilabel(app.UIFigure);
            app.ApproximateRootTextAreaLabel.Text = 'Approximate Root=';
            app.ApproximateRootTextArea = uitextarea(app.UIFigure);
            app.ApproximateRootTextArea.Editable = 'off';
            app.CalulateButton = uibutton(app.UIFigure, 'push');
            app.CalulateButton.ButtonPushedFcn = createCallbackFcn(app, @CalulateButtonPushed, true);
            app.CalulateButton.Text = 'Calulate';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iterations'; 'a'; 'b'; 'f(a)'; 'f(b)'; 'p'; 'f(p)'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualization of Bisection Method')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YAxisLocation = 'origin';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Bisection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end